classdef tcCryoLayout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        Position0EditFieldLabel    matlab.ui.control.Label
        Position0EditField         matlab.ui.control.EditField
        Position1EditFieldLabel    matlab.ui.control.Label
        Position1EditField         matlab.ui.control.EditField
        Position2EditFieldLabel    matlab.ui.control.Label
        Position2EditField         matlab.ui.control.EditField
        Position3EditFieldLabel    matlab.ui.control.Label
        Position3EditField         matlab.ui.control.EditField
        Position4EditFieldLabel    matlab.ui.control.Label
        Position4EditField         matlab.ui.control.EditField
        Position5EditFieldLabel    matlab.ui.control.Label
        Position5EditField         matlab.ui.control.EditField
        Tc0                        matlab.ui.control.NumericEditField
        SampleIDLabel              matlab.ui.control.Label
        TcLabel                    matlab.ui.control.Label
        Tc1                        matlab.ui.control.NumericEditField
        Tc2                        matlab.ui.control.NumericEditField
        Tc3                        matlab.ui.control.NumericEditField
        Tc4                        matlab.ui.control.NumericEditField
        Tc5                        matlab.ui.control.NumericEditField
        TabGroup                   matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        EditField0                 matlab.ui.control.NumericEditField
        EditField1                 matlab.ui.control.NumericEditField
        EditField2                 matlab.ui.control.NumericEditField
        EditField3                 matlab.ui.control.NumericEditField
        EditField4                 matlab.ui.control.NumericEditField
        EditField5                 matlab.ui.control.NumericEditField
        CurrentListBoxLabel        matlab.ui.control.Label
        CurrentListBox             matlab.ui.control.ListBox
        EditField0_1               matlab.ui.control.NumericEditField
        EditField1_1               matlab.ui.control.NumericEditField
        EditField2_1               matlab.ui.control.NumericEditField
        EditField3_1               matlab.ui.control.NumericEditField
        EditField4_1               matlab.ui.control.NumericEditField
        EditField5_1               matlab.ui.control.NumericEditField
        TestConnectionsButton      matlab.ui.control.StateButton
        ResistanceLabel            matlab.ui.control.Label
        StdLabel                   matlab.ui.control.Label
        Tab2                       matlab.ui.container.Tab
        UIAxes7                    matlab.ui.control.UIAxes
        TabGroup2                  matlab.ui.container.TabGroup
        Tab_2                      matlab.ui.container.Tab
        UIAxes4_6                  matlab.ui.control.UIAxes
        UIAxes4_5                  matlab.ui.control.UIAxes
        UIAxes4_4                  matlab.ui.control.UIAxes
        UIAxes4_3                  matlab.ui.control.UIAxes
        UIAxes4_2                  matlab.ui.control.UIAxes
        UIAxes4_1                  matlab.ui.control.UIAxes
        Tab2_2                     matlab.ui.container.Tab
        UIAxes8                    matlab.ui.control.UIAxes
        QuickMeasureSwitchLabel    matlab.ui.control.Label
        QuickMeasureSwitch         matlab.ui.control.Switch
        Panel2                     matlab.ui.container.Panel
        FieldCheckCheckBox         matlab.ui.control.CheckBox
        RunButton                  matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        StatusEditFieldLabel       matlab.ui.control.Label
        StatusEditField            matlab.ui.control.EditField
        MinTempEditFieldLabel      matlab.ui.control.Label
        MinTempEditField           matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel  matlab.ui.control.Label
        TemperatureEditField       matlab.ui.control.NumericEditField
        DistanceEditFieldLabel     matlab.ui.control.Label
        DistanceEditField          matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.StopButton.Enable = 'on';
            samples = string(app.Panel.Children.findobj('Type','uieditfield').get('Value'));
            positions = find(flipud(~cellfun(@isempty,samples)));

            c = app.checkSampleNames();
            
            if app.FieldCheckCheckBox.Value == 1 && ~isempty(positions) && c == 1
                app.RunButton.Enable = 'off';
                
                app.TestConnectionsButton.Value = 0;
                app.TestConnectionsButton.Enable = 'off';
                
%                 app.QuickMeasureSwitch.Value = 0;
                app.QuickMeasureSwitch.Enable = 'off';
            
                app.startTcMeasurement();
            else
                app.StatusEditField.Value = 'Enter Sample Names With Format: ABC123';
            end
        end

        % Value changed function: FieldCheckCheckBox
        function FieldCheckCheckBoxValueChanged(app, event)
            value = app.FieldCheckCheckBox.Value;
            
        end

        % Value changed function: CurrentListBox
        function CurrentListBoxValueChanged(app, event)
            value = app.CurrentListBox.Value;
            
        end

        % Value changed function: TestConnectionsButton
        function TestConnectionsButtonValueChanged(app, event)
            value = app.TestConnectionsButton.Value;
            app.startTestConnections();
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            f = uifigure;
            selection = uiconfirm(f,'Confirm Stop?', 'Stop Button Pushed','Icon','warning');
            delete(f)
            if selection == 'OK'
                app.cryoconObj.stop_heater();
                app.StatusEditField.Value = 'System Stopped';
                app.stop = 1;
                app.RunButton.Enable = 'on';
                app.StopButton.Enable = 'off';
                app.TestConnectionsButton.Enable = 'on';
                app.voltage = [];
                app.temp = [];
                
%                 app.UIAxes4_1.cla
%                 app.UIAxes4_2.cla
%                 app.UIAxes4_3.cla
%                 app.UIAxes4_4.cla
%                 app.UIAxes4_5.cla
%                 app.UIAxes4_6.cla
                
                cla(app.UIAxes4_1)
                cla(app.UIAxes4_2)
                cla(app.UIAxes4_3)
                cla(app.UIAxes4_4)
                cla(app.UIAxes4_5)
                cla(app.UIAxes4_6)
                
                app.cryoconObj.stop_heater();
%                 cooldownTemp
%                 cooldownTime
%                 cooldownDuration
%                 measuredResistance
%                 measuredTemp
%                 measuredCurrent
%                 globalMinimumTemp
            end
        end

        % Value changed function: QuickMeasureSwitch
        function QuickMeasureSwitchValueChanged(app, event)
            value = app.QuickMeasureSwitch.Value;
            app.updateTemp(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Position0EditFieldLabel = uilabel(app.Panel);
            app.Position0EditFieldLabel.Text = 'Position 0';
            app.Position0EditField = uieditfield(app.Panel, 'text');
            app.Position1EditFieldLabel = uilabel(app.Panel);
            app.Position1EditFieldLabel.Text = 'Position 1';
            app.Position1EditField = uieditfield(app.Panel, 'text');
            app.Position2EditFieldLabel = uilabel(app.Panel);
            app.Position2EditFieldLabel.Text = 'Position 2';
            app.Position2EditField = uieditfield(app.Panel, 'text');
            app.Position3EditFieldLabel = uilabel(app.Panel);
            app.Position3EditFieldLabel.Text = 'Position 3';
            app.Position3EditField = uieditfield(app.Panel, 'text');
            app.Position4EditFieldLabel = uilabel(app.Panel);
            app.Position4EditFieldLabel.Text = 'Position 4';
            app.Position4EditField = uieditfield(app.Panel, 'text');
            app.Position5EditFieldLabel = uilabel(app.Panel);
            app.Position5EditFieldLabel.Text = 'Position 5';
            app.Position5EditField = uieditfield(app.Panel, 'text');
            app.Tc0 = uieditfield(app.Panel, 'numeric');
            app.Tc0.Editable = 'off';
            app.SampleIDLabel = uilabel(app.Panel);
            app.SampleIDLabel.Text = 'Sample ID';
            app.TcLabel = uilabel(app.Panel);
            app.TcLabel.Text = 'Tc';
            app.Tc1 = uieditfield(app.Panel, 'numeric');
            app.Tc1.Editable = 'off';
            app.Tc2 = uieditfield(app.Panel, 'numeric');
            app.Tc2.Editable = 'off';
            app.Tc3 = uieditfield(app.Panel, 'numeric');
            app.Tc3.Editable = 'off';
            app.Tc4 = uieditfield(app.Panel, 'numeric');
            app.Tc4.Editable = 'off';
            app.Tc5 = uieditfield(app.Panel, 'numeric');
            app.Tc5.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.EditField0 = uieditfield(app.Tab, 'numeric');
            app.EditField0.Editable = 'off';
            app.EditField1 = uieditfield(app.Tab, 'numeric');
            app.EditField1.Editable = 'off';
            app.EditField2 = uieditfield(app.Tab, 'numeric');
            app.EditField2.Editable = 'off';
            app.EditField3 = uieditfield(app.Tab, 'numeric');
            app.EditField3.Editable = 'off';
            app.EditField4 = uieditfield(app.Tab, 'numeric');
            app.EditField4.Editable = 'off';
            app.EditField5 = uieditfield(app.Tab, 'numeric');
            app.EditField5.Editable = 'off';
            app.CurrentListBoxLabel = uilabel(app.Tab);
            app.CurrentListBoxLabel.Text = 'Current';
            app.CurrentListBox = uilistbox(app.Tab);
            app.CurrentListBox.Items = {'100nA', '300nA', '1uA', '3uA', '10uA', '30uA', '100uA', '300uA', '1mA', '3mA', '10mA', '30mA', '100mA'};
            app.CurrentListBox.ItemsData = {'100e-9', '300e-9', '1e-6', '3e-6', '10e-6', '30e-6', '100e-6', '300e-6', '1e-3', '3e-3', '10e-3', '30e-3', '100e-3'};
            app.CurrentListBox.ValueChangedFcn = createCallbackFcn(app, @CurrentListBoxValueChanged, true);
            app.CurrentListBox.Value = '300e-6';
            app.EditField0_1 = uieditfield(app.Tab, 'numeric');
            app.EditField0_1.Editable = 'off';
            app.EditField1_1 = uieditfield(app.Tab, 'numeric');
            app.EditField1_1.Editable = 'off';
            app.EditField2_1 = uieditfield(app.Tab, 'numeric');
            app.EditField2_1.Editable = 'off';
            app.EditField3_1 = uieditfield(app.Tab, 'numeric');
            app.EditField3_1.Editable = 'off';
            app.EditField4_1 = uieditfield(app.Tab, 'numeric');
            app.EditField4_1.Editable = 'off';
            app.EditField5_1 = uieditfield(app.Tab, 'numeric');
            app.EditField5_1.Editable = 'off';
            app.TestConnectionsButton = uibutton(app.Tab, 'state');
            app.TestConnectionsButton.ValueChangedFcn = createCallbackFcn(app, @TestConnectionsButtonValueChanged, true);
            app.TestConnectionsButton.Text = 'Test Connections';
            app.ResistanceLabel = uilabel(app.Tab);
            app.ResistanceLabel.Text = 'Resistance';
            app.StdLabel = uilabel(app.Tab);
            app.StdLabel.Text = 'Std.';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes7 = uiaxes(app.Tab2);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = 'Tab';
            app.UIAxes4_6 = uiaxes(app.Tab_2);
            title(app.UIAxes4_6, 'Position 5')
            xlabel(app.UIAxes4_6, 'Temperature')
            ylabel(app.UIAxes4_6, 'Resistance')
            app.UIAxes4_5 = uiaxes(app.Tab_2);
            title(app.UIAxes4_5, 'Position 4')
            xlabel(app.UIAxes4_5, 'Temperature')
            ylabel(app.UIAxes4_5, 'Resistance')
            app.UIAxes4_4 = uiaxes(app.Tab_2);
            title(app.UIAxes4_4, 'Position 3')
            xlabel(app.UIAxes4_4, 'Temperature')
            ylabel(app.UIAxes4_4, 'Resistance')
            app.UIAxes4_3 = uiaxes(app.Tab_2);
            title(app.UIAxes4_3, 'Position 2')
            xlabel(app.UIAxes4_3, 'Temperature')
            ylabel(app.UIAxes4_3, 'Resistance')
            app.UIAxes4_2 = uiaxes(app.Tab_2);
            title(app.UIAxes4_2, 'Position 1')
            xlabel(app.UIAxes4_2, 'Temperature')
            ylabel(app.UIAxes4_2, 'Resistance')
            app.UIAxes4_1 = uiaxes(app.Tab_2);
            title(app.UIAxes4_1, 'Position 0')
            xlabel(app.UIAxes4_1, 'Temperature')
            ylabel(app.UIAxes4_1, 'Resistance')
            app.Tab2_2 = uitab(app.TabGroup2);
            app.Tab2_2.Title = 'Tab2';
            app.UIAxes8 = uiaxes(app.Tab2_2);
            title(app.UIAxes8, 'Cooldown')
            xlabel(app.UIAxes8, 'Time')
            ylabel(app.UIAxes8, 'Temperature (K)')
            app.QuickMeasureSwitchLabel = uilabel(app.Tab2_2);
            app.QuickMeasureSwitchLabel.Text = 'Quick Measure';
            app.QuickMeasureSwitch = uiswitch(app.Tab2_2, 'slider');
            app.QuickMeasureSwitch.ValueChangedFcn = createCallbackFcn(app, @QuickMeasureSwitchValueChanged, true);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.FieldCheckCheckBox = uicheckbox(app.Panel2);
            app.FieldCheckCheckBox.ValueChangedFcn = createCallbackFcn(app, @FieldCheckCheckBoxValueChanged, true);
            app.FieldCheckCheckBox.Text = 'Field Check';
            app.RunButton = uibutton(app.Panel2, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.Panel2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StatusEditField.Editable = 'off';
            app.MinTempEditFieldLabel = uilabel(app.UIFigure);
            app.MinTempEditFieldLabel.Text = 'Min Temp';
            app.MinTempEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinTempEditField.Limits = [0 Inf];
            app.MinTempEditField.ValueDisplayFormat = '%.3f';
            app.MinTempEditField.Editable = 'off';
            app.TemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureEditField.Limits = [0 Inf];
            app.TemperatureEditField.ValueDisplayFormat = '%.3f';
            app.TemperatureEditField.Editable = 'off';
            app.DistanceEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanceEditField.Limits = [0 Inf];
            app.DistanceEditField.ValueDisplayFormat = '%.0f';
            app.DistanceEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tcCryoLayout

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end